classdef interfazfrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfazGraficaUIFigure        matlab.ui.Figure
        ArchiveMenu                    matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        TablexlsxMenu                  matlab.ui.container.Menu
        IRJPGMenu                      matlab.ui.container.Menu
        SmoothIRJPGMenu                matlab.ui.container.Menu
        FrecuencyResponseJPGMenu       matlab.ui.container.Menu
        PreferencesMenu                matlab.ui.container.Menu
        DeviceMenu                     matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        Graph                          matlab.ui.container.Tab
        PlotIR                         matlab.ui.control.UIAxes
        OctaveFiltersDropDownLabel     matlab.ui.control.Label
        OctaveFiltersDropDown          matlab.ui.control.DropDown
        ThirdsOctaveFiltersDropDownLabel  matlab.ui.control.Label
        ThirdsOctaveFiltersDropDown    matlab.ui.control.DropDown
        NoFilterCheckBox               matlab.ui.control.CheckBox
        Graph_2                        matlab.ui.container.Tab
        PlotSmoothIR                   matlab.ui.control.UIAxes
        RegLinCheckBox                 matlab.ui.control.CheckBox
        NoFilterCheckBox_2             matlab.ui.control.CheckBox
        OctaveFiltersDropDown_3Label   matlab.ui.control.Label
        OctaveFiltersDropDown_3        matlab.ui.control.DropDown
        ThirdsOctaveFiltersDropDown_3Label  matlab.ui.control.Label
        ThirdsOctaveFiltersDropDown_3  matlab.ui.control.DropDown
        FrecuencyResponseTab           matlab.ui.container.Tab
        PlotFrecuencyResponse          matlab.ui.control.UIAxes
        NoFilterCheckBox_3             matlab.ui.control.CheckBox
        OctaveFiltersDropDown_4Label   matlab.ui.control.Label
        OctaveFiltersDropDown_4        matlab.ui.control.DropDown
        ThirdsOctaveFiltersDropDown_4Label  matlab.ui.control.Label
        ThirdsOctaveFiltersDropDown_4  matlab.ui.control.DropDown
        Table                          matlab.ui.container.Tab
        Tabla                          matlab.ui.control.Table
        DropDown                       matlab.ui.control.DropDown
        Operaciones                    matlab.ui.container.TabGroup
        CalibrateTab                   matlab.ui.container.Tab
        PlaypinknoisetocalibrateyoursystemLabel  matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        Vumetro                        matlab.ui.control.LinearGauge
        Switch                         matlab.ui.control.RockerSwitch
        PlayRecTab                     matlab.ui.container.Tab
        StartHzLabel                   matlab.ui.control.Label
        StartHzEditField               matlab.ui.control.NumericEditField
        EndHzLabel                     matlab.ui.control.Label
        EndHzEditField                 matlab.ui.control.NumericEditField
        DurationSecEditFieldLabel      matlab.ui.control.Label
        DurationSecEditField           matlab.ui.control.NumericEditField
        StartMeasurementButton         matlab.ui.control.Button
        PlayandrecordasinesweepinordertoperformthemeasurementLabel  matlab.ui.control.Label
        RepetitionsEditFieldLabel      matlab.ui.control.Label
        RepetitionsEditField           matlab.ui.control.NumericEditField
        ImportTab                      matlab.ui.container.Tab
        ImportButton                   matlab.ui.control.StateButton
        CalculateButton                matlab.ui.control.StateButton
        Tree                           matlab.ui.container.Tree
        Node                           matlab.ui.container.TreeNode
        Node2                          matlab.ui.container.TreeNode
        Node3                          matlab.ui.container.TreeNode
        Node4                          matlab.ui.container.TreeNode
    end


    methods (Access = private)

        % Value changed function: Switch
        function PlayNoiseButtonPushed(app, event)
             val = app.Switch.Value
             if val == "On"
                 Pink = audioread("Pink Noise.wav");
                 sound(Pink,44100);
             end
             if val == "Off"
                 clear sound
             end
        end

        % Menu selected function: DeviceMenu
        function DeviceMenuSelected(app, event)
         open(devices);
         
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)
            Start = app.StartHzEditField.Value;
            End = app.EndHzEditField.Value;
            Duration = app.DurationSecEditField.Value;
            try 
                playRec(Start,End,Duration)
            catch 
                warning("No Full Duplex Devices")
            end
        end

        % Value changed function: ImportButton
        function ImportButtonValueChanged(app, event)
           Import = uiimport('-file');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfazGraficaUIFigure = uifigure;
            app.InterfazGraficaUIFigure.Name = 'Interfaz Grafica';
            app.ArchiveMenu = uimenu(app.InterfazGraficaUIFigure);
            app.ArchiveMenu.Text = 'Archive';
            app.ExportMenu = uimenu(app.ArchiveMenu);
            app.ExportMenu.Text = 'Export';
            app.TablexlsxMenu = uimenu(app.ExportMenu);
            app.TablexlsxMenu.Text = 'Table (.xlsx)';
            app.IRJPGMenu = uimenu(app.ExportMenu);
            app.IRJPGMenu.Text = 'IR (JPG)';
            app.SmoothIRJPGMenu = uimenu(app.ExportMenu);
            app.SmoothIRJPGMenu.Text = 'Smooth IR (JPG)';
            app.FrecuencyResponseJPGMenu = uimenu(app.ExportMenu);
            app.FrecuencyResponseJPGMenu.Text = 'Frecuency Response(JPG)';
            app.PreferencesMenu = uimenu(app.ArchiveMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.DeviceMenu = uimenu(app.PreferencesMenu);
            app.DeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeviceMenuSelected, true);
            app.DeviceMenu.Text = 'Device';
            app.HelpMenu = uimenu(app.InterfazGraficaUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ExitMenu = uimenu(app.InterfazGraficaUIFigure);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.InterfazGraficaUIFigure);
            app.Graph = uitab(app.TabGroup);
            app.Graph.Title = 'IR';
            app.PlotIR = uiaxes(app.Graph);
            title(app.PlotIR, 'IR')
            xlabel(app.PlotIR, 'Time [Sec]')
            ylabel(app.PlotIR, 'Amplitude [dB]')
            app.OctaveFiltersDropDownLabel = uilabel(app.Graph);
            app.OctaveFiltersDropDownLabel.Text = ' Octave Filters';
            app.OctaveFiltersDropDown = uidropdown(app.Graph);
            app.OctaveFiltersDropDown.Items = {'31,5 Hz', '63 Hz', '125 Hz', '250 Hz', '500 Hz', '1000 Hz', '2000 Hz', '4000 Hz', '8000 Hz', '16000 Hz'};
            app.OctaveFiltersDropDown.Value = '31,5 Hz';
            app.ThirdsOctaveFiltersDropDownLabel = uilabel(app.Graph);
            app.ThirdsOctaveFiltersDropDownLabel.Text = 'Thirds Octave Filters';
            app.ThirdsOctaveFiltersDropDown = uidropdown(app.Graph);
            app.ThirdsOctaveFiltersDropDown.Items = {'25 Hz', '31,5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '3150 Hz', '3500 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10000 Hz', '12500 Hz', '16000 Hz'};
            app.ThirdsOctaveFiltersDropDown.Value = '25 Hz';
            app.NoFilterCheckBox = uicheckbox(app.Graph);
            app.NoFilterCheckBox.Text = 'No Filter';
            app.Graph_2 = uitab(app.TabGroup);
            app.Graph_2.Title = 'Smooth IR';
            app.PlotSmoothIR = uiaxes(app.Graph_2);
            title(app.PlotSmoothIR, 'Smooth IR')
            xlabel(app.PlotSmoothIR, 'Time [Sec]')
            ylabel(app.PlotSmoothIR, 'Amplitude [dB]')
            app.RegLinCheckBox = uicheckbox(app.Graph_2);
            app.RegLinCheckBox.Text = 'Reg Lin';
            app.NoFilterCheckBox_2 = uicheckbox(app.Graph_2);
            app.NoFilterCheckBox_2.Text = 'No Filter';
            app.OctaveFiltersDropDown_3Label = uilabel(app.Graph_2);
            app.OctaveFiltersDropDown_3Label.Text = ' Octave Filters';
            app.OctaveFiltersDropDown_3 = uidropdown(app.Graph_2);
            app.OctaveFiltersDropDown_3.Items = {'31,5 Hz', '63 Hz', '125 Hz', '250 Hz', '500 Hz', '1000 Hz', '2000 Hz', '4000 Hz', '8000 Hz', '16000 Hz'};
            app.OctaveFiltersDropDown_3.Value = '31,5 Hz';
            app.ThirdsOctaveFiltersDropDown_3Label = uilabel(app.Graph_2);
            app.ThirdsOctaveFiltersDropDown_3Label.Text = 'Thirds Octave Filters';
            app.ThirdsOctaveFiltersDropDown_3 = uidropdown(app.Graph_2);
            app.ThirdsOctaveFiltersDropDown_3.Items = {'25 Hz', '31,5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '3150 Hz', '3500 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10000 Hz', '12500 Hz', '16000 Hz'};
            app.ThirdsOctaveFiltersDropDown_3.Value = '25 Hz';
            app.FrecuencyResponseTab = uitab(app.TabGroup);
            app.FrecuencyResponseTab.Title = 'Frecuency Response';
            app.PlotFrecuencyResponse = uiaxes(app.FrecuencyResponseTab);
            title(app.PlotFrecuencyResponse, 'Frecuency Response')
            xlabel(app.PlotFrecuencyResponse, 'Time [Sec]')
            ylabel(app.PlotFrecuencyResponse, 'Amplitude [dB]')
            app.NoFilterCheckBox_3 = uicheckbox(app.FrecuencyResponseTab);
            app.NoFilterCheckBox_3.Text = 'No Filter';
            app.OctaveFiltersDropDown_4Label = uilabel(app.FrecuencyResponseTab);
            app.OctaveFiltersDropDown_4Label.Text = ' Octave Filters';
            app.OctaveFiltersDropDown_4 = uidropdown(app.FrecuencyResponseTab);
            app.OctaveFiltersDropDown_4.Items = {'31,5 Hz', '63 Hz', '125 Hz', '250 Hz', '500 Hz', '1000 Hz', '2000 Hz', '4000 Hz', '8000 Hz', '16000 Hz'};
            app.OctaveFiltersDropDown_4.Value = '31,5 Hz';
            app.ThirdsOctaveFiltersDropDown_4Label = uilabel(app.FrecuencyResponseTab);
            app.ThirdsOctaveFiltersDropDown_4Label.Text = 'Thirds Octave Filters';
            app.ThirdsOctaveFiltersDropDown_4 = uidropdown(app.FrecuencyResponseTab);
            app.ThirdsOctaveFiltersDropDown_4.Items = {'25 Hz', '31,5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '3150 Hz', '3500 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10000 Hz', '12500 Hz', '16000 Hz'};
            app.ThirdsOctaveFiltersDropDown_4.Value = '25 Hz';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.Tabla = uitable(app.Table);
            app.Tabla.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tabla.RowName = {};
            app.DropDown = uidropdown(app.Table);
            app.DropDown.Items = {'Octave Filter', 'Third Octave Filter', 'No Filter'};
            app.DropDown.Value = 'Octave Filter';
            app.Operaciones = uitabgroup(app.InterfazGraficaUIFigure);
            app.CalibrateTab = uitab(app.Operaciones);
            app.CalibrateTab.Title = 'Calibrate';
            app.PlaypinknoisetocalibrateyoursystemLabel = uilabel(app.CalibrateTab);
            app.PlaypinknoisetocalibrateyoursystemLabel.Text = 'Play pink noise to calibrate your system.';
            app.dBLabel = uilabel(app.CalibrateTab);
            app.dBLabel.Text = '[dB]';
            app.Vumetro = uigauge(app.CalibrateTab, 'linear');
            app.Vumetro.Limits = [-20 4];
            app.Vumetro.Value = -20;
            app.Switch = uiswitch(app.CalibrateTab, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @PlayNoiseButtonPushed, true);
            app.PlayRecTab = uitab(app.Operaciones);
            app.PlayRecTab.Title = 'Play/Rec';
            app.StartHzLabel = uilabel(app.PlayRecTab);
            app.StartHzLabel.Text = 'Start [Hz]';
            app.StartHzEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.StartHzEditField.Limits = [0 20000];
            app.EndHzLabel = uilabel(app.PlayRecTab);
            app.EndHzLabel.Text = 'End [Hz]';
            app.EndHzEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.EndHzEditField.Limits = [0 40000];
            app.DurationSecEditFieldLabel = uilabel(app.PlayRecTab);
            app.DurationSecEditFieldLabel.Text = 'Duration [Sec]';
            app.DurationSecEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.DurationSecEditField.Limits = [0 60];
            app.StartMeasurementButton = uibutton(app.PlayRecTab, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.PlayandrecordasinesweepinordertoperformthemeasurementLabel = uilabel(app.PlayRecTab);
            app.PlayandrecordasinesweepinordertoperformthemeasurementLabel.Text = ' Play and record a sine sweep in order to perform the measurement.';
            app.RepetitionsEditFieldLabel = uilabel(app.PlayRecTab);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.RepetitionsEditField.Limits = [0 40000];
            app.ImportTab = uitab(app.Operaciones);
            app.ImportTab.Title = 'Import';
            app.ImportButton = uibutton(app.ImportTab, 'state');
            app.ImportButton.ValueChangedFcn = createCallbackFcn(app, @ImportButtonValueChanged, true);
            app.ImportButton.Text = 'Import';
            app.CalculateButton = uibutton(app.ImportTab, 'state');
            app.CalculateButton.Text = 'Calculate';
            app.Tree = uitree(app.ImportTab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interfazfrafica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfazGraficaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfazGraficaUIFigure)
        end
    end
end